classdef Liveupdate_ecci_profile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECCILineprofilesUIFigure  matlab.ui.Figure
        StopButton                matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        LiveGenerate          % Timer object
        PlotGraph           % Plotobject
        data
    end

    properties (Access = public)
        workingDir % Working Directory
        imageNames % Image Names
        X          %Pixel position of the line profile
        Y          %Pixel position of the line profile
        %data       % X,Y data of Intensity and stage R
        i
        conti
        imageset
        refimage
        currimage
    end

    methods (Access = private)

        function LiveGenerateFcn(app,~,~)
            % read file and values
            %randnum = rand;

            % Update YData in plot
            %ydata = app.PlotLine.YData;
            %ydata = circshift(ydata,1);
            %ydata(1) = randnum;
            %app.PlotLine.YData = ydata;
            %            app.PlotLine = plot(app.UIAxes,data(:,1),data(:,2));
            i=app.conti;
            if anynan(app.data(:,1))
                app.imageNames=dir(fullfile(app.workingDir,'*.tif'));
                app.imageNames(1)=[];
                app.imageNames = {app.imageNames.name}';

                for j=i:length(app.imageNames)

                    img=imread([app.workingDir '\' app.imageNames{j}]);
                    if isnan(sum(app.imageset(:,:,j),'all'))
                        img=imcrop(img,[0 0 1024 700]);
                        imgtags=imfinfo([app.workingDir '\' app.imageNames{j}]);  %Image tags are read here to determine how much to rotate back
                        imgtags=imgtags.UnknownTags.Value;
                        s=strfind(imgtags,'Stage at R');
                        imgtags=str2num(imgtags(s+13:s+18));
                        if imgtags==360
                            imgtags=0;
                        end
                        img=imrotate(img,imgtags,'nearest','crop');
                        img=shifted_n_aligned(app,app.refimage,img);
                        app.imageset(:,:,j)=img;
                        app.data(j,1)=imgtags;
                        app.data(j,2)=app.imageset(app.X,app.Y,j);
                    end
                    app.conti=j;
                end
                
                set(app.PlotGraph,'YData',app.data(:,2));
                set(app.PlotGraph,'XData',app.data(:,1));

                %                 app.Plot.XData = app.data(:,1);
                %                 app.Plot.YData=app.data(:,2);


            end

        end


        function results = shifted_n_aligned(app,rimg,cimg)
            [optimizer, metric]  = imregconfig('monomodal');
            optimizer.MaximumIterations=100;
            optimizer.GradientMagnitudeTolerance=1e-4;
            optimizer.MinimumStepLength=1e-7;

            croppedrefimage=imcrop(rimg,[150 150 size(rimg,2)-150 size(rimg,1)-150]);
            currentimage=imcrop(cimg,[150 150 size(cimg,2)-100 size(cimg,1)-150]);
            tformEstimate = imregcorr(currentimage,croppedrefimage,'translation');
            results = imwarp(cimg,tformEstimate,'OutputView',imref2d(size(rimg)));

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            app.data=NaN(360,2);
            app.imageset=NaN([700,1024,360]);
            app.UIAxes.XLim = [1 360];
            app.UIAxes.XDir = 'normal';
            app.UIAxes.YLim = [0 255];

            % Initial plot is all zeros
            app.PlotGraph = plot(app.UIAxes,1:360,zeros(1,360));

            % Create timer object
            app.LiveGenerate = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 1, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.LiveGenerateFcn);      % Specify callback function
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.workingDir=uigetdir;
            app.imageNames=dir(fullfile(app.workingDir,'*.tif'));
            app.imageNames(1)=[];
            app.imageNames = {app.imageNames.name}';
            title(app.UIAxes, 'ECCI Live Script - Running')
            UIAxes = imshow([app.workingDir '\' app.imageNames{1}]);
            %uiwait(msgbox("Select a point to plot a profile","Note","modal"));
            hold on
            [app.X,app.Y,~] = ginput(1);
            close
            title(app.UIAxes, 'ECCI Live Script - Running')
            app.refimage=imcrop(imread([app.workingDir '\' app.imageNames{1}]),[0 0 1024 700]);
            app.imageset(:,:,1)=app.refimage;
            initialR=imfinfo([app.workingDir '\' app.imageNames{1}]);
            initialR=initialR.UnknownTags.Value;
            s=strfind(initialR,'Stage at R');
            initialR=str2num(initialR(s+13:s+18));
            if initialR==360
                initialR=0;
            end
            app.data(1,1)=initialR;
            app.data(1,2)=app.imageset(app.X,app.Y,1);
            for i = 2:length(app.imageNames)
                img=imcrop(imread([app.workingDir '\' app.imageNames{i}]),[0 0 1024 700]);
                imgtags=imfinfo([app.workingDir '\' app.imageNames{i}]);  %Image tags are read here to determine how much to rotate back
                imgtags=imgtags.UnknownTags.Value;
                s=strfind(imgtags,'Stage at R');
                imgtags=str2num(imgtags(s+13:s+18));
                if imgtags==360
                    imgtags=0;
                end
                img=imrotate(img,imgtags,'nearest','crop');
                img=shifted_n_aligned(app,app.refimage,img);
                app.imageset(:,:,i)=img;
                app.data(i,1)=imgtags;
                app.data(i,2)=app.imageset(app.X,app.Y,i);
            end
            app.conti=i;
            app.PlotGraph = plot(app.UIAxes,app.data(:,1),app.data(:,2));
            if strcmp(app.LiveGenerate.Running, 'off')
                start(app.LiveGenerate);
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            title(app.UIAxes, 'ECCI Live Script - Stopped')
            stop(app.LiveGenerate);
            imshow(app.imageset(:,:,1));
            clim([0 255])
            colormap gray
            hold on
            scatter(app.X,app.Y,'r','filled')
        end

        % Close request function: ECCILineprofilesUIFigure
        function ECCILineprofilesUIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and app
            stop(app.LiveGenerate);
            delete(app.LiveGenerate);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ECCILineprofilesUIFigure = uifigure('Visible', 'off');
            app.ECCILineprofilesUIFigure.Name = 'ECCI Line profiles';
            app.ECCILineprofilesUIFigure.CloseRequestFcn = createCallbackFcn(app, @ECCILineprofilesUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.ECCILineprofilesUIFigure);
            title(app.UIAxes, 'ECCI Live Script')
            xlabel(app.UIAxes, 'Stage R')
            ylabel(app.UIAxes, 'Intensity Value')
            app.StartButton = uibutton(app.ECCILineprofilesUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ECCILineprofilesUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.ECCILineprofilesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Liveupdate_ecci_profile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECCILineprofilesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECCILineprofilesUIFigure)
        end
    end
end